classdef DAR < matlab.ui.componentcontainer.ComponentContainer
    %DAR A single lab procedure status and data
    %   This component is used in the Lab Procedure app to track and
    %   update data associated with a procedure

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout2             matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        AnlisisdedatosLabel     matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        CargadedatosTab         matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        RealizaranlisisButton   matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ImportDataButton        matlab.ui.control.Button
        MtododeanlisisLabel     matlab.ui.control.Label
        StatusDropDown          matlab.ui.control.DropDown
        VisualizacindedatosTab  matlab.ui.container.Tab
        GridLayout4             matlab.ui.container.GridLayout
        BtnGraph2               matlab.ui.control.Button
        BtnGraph1               matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes1                 matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        StatusChanged
    end

    properties (Access = private)
        DataFilename % Path to data file
    end
    
    properties (Access = public)
        Status (1,1) string {mustBeMember(Status, ["PCA", "LDA", "CDA"])} = "PCA"; % Lab procedure method
        Title (1,1) string {mustBeTextScalar(Title)} = "Lab Data Analysis"; % Lab procedure title
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.VisualizacindedatosTab.Parent = []; % para que no muestra el tab de análisis
            comp.UIAxes1.FontSize = 16;
            comp.UIAxes2.FontSize = 16;
        end

        % Callback function
        function StatusDropDownValueChanged(comp, event)
            % Update public property and trigger event
            comp.Status = comp.StatusDropDown.Value;
            notify(comp,"StatusChanged")
        end

        % Callback function
        function DatePickerValueChanged(comp, event)
            % Update public property and trigger event

            notify(comp,"DateChanged");
        end

        % Callback function
        function ViewDataButtonPushed(comp, event)
            open(comp.DataFilename);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(comp, event)
            [f,p] = uigetfile({'*.xlsx'},'File Selector');

            % Store data
            if f ~= 0
                comp.DataFilename = fullfile(p,f);
                disp(comp.DataFilename);
                t = readtable( ...
                    comp.DataFilename,"Sheet",1);
                comp.UITable.Data = t;
                comp.UITable.ColumnName = t.Properties.VariableNames;
                %Activación de los botones
                comp.MtododeanlisisLabel.Enable = "on";
                comp.StatusDropDown.Enable = "on";
                comp.RealizaranlisisButton.Enable = "on";
            end
        end

        % Callback function
        function ImportDataButtonPushed2(comp, event)

        end

        % Button pushed function: RealizaranlisisButton
        function RealizaranlisisButtonPushed(comp, event)
            comp.VisualizacindedatosTab.Parent = comp.TabGroup; %Anclamos al tab principal
            comp.TabGroup.SelectedTab = comp.VisualizacindedatosTab; %Mostramos el tab
            % Calcular el PCA

            datas = table2array(comp.UITable.Data(:,1:end-1));
            classes = table2array(comp.UITable.Data(:,end:end));
            variables = comp.UITable.ColumnName(1:end-1);
            if (comp.StatusDropDown.Value == "PCA")
                [coeff,score,latent,~,explained] = pca(datas,"Centered",true,"Algorithm","eig");
                h = biplot(comp.UIAxes1, coeff(:,1:2),'Scores',score(:,1:2),'ObsLabels',cellstr(classes),'Varlabels',variables);

            elseif(comp.StatusDropDown.Value == "CDA")
                featureMatrix = normalize(datas,"center","mean");
                classesUnique = categorical(classes);
                %classesUnique = cell(classesUnique);
                %disp(string(classesUnique));
                [eigenvectors, eigenvalues, projections, explained, indexes] = My_CDA(featureMatrix,classesUnique);
                
                %n_classes = length(classes);
                varLabels = variables(indexes);
                h = biplot(comp.UIAxes1,eigenvectors(:,1:2),'Scores',projections(:,1:2),'ObsLabels',cellstr(classes),'Varlabels',varLabels); 
                s = spider_plot(comp.UIAxes2, projections, 'AxesLabels',varLabels);
            end


            symbols = {'x', 'o', 'square', 'd', '<','>','*','+','.','|','v'};
            % Identify each handle
            hID = get(h, 'tag');
            % Isolate handles to scatter points
            hPt = h(strcmp(hID,'obsmarker'));
            % Identify cluster groups
            grp = findgroups(classes);    %r2015b or later - leave comment if you need an alternative
            %grp(isnan(grp)) = max(grp(~isnan(grp)))+1;
            grpID = 1:max(grp);
            lbl = unique(classes);
            % assign colors and legend display name
            clrMap = lines(length(unique(grp)));   % using 'lines' colormap
            for i = 1:max(grp)
                set(hPt(grp==i), 'Color', clrMap(i,:),'markersize', 12, 'Marker',symbols{i},'DisplayName', string(lbl(i)))
            end
            % add legend to identify cluster
            [~, unqIdx] = unique(grp);
            legend(hPt(unqIdx))
            txt = ['Componente 1 (' int2str(explained(1)) '%)'];
            comp.UIAxes1.XLabel.String = txt;
            %comp.UIAxes1.XLabel.FontSize = 14;
            txt = ['Componente 2 (' int2str(explained(2)) '%)'];
            comp.UIAxes1.YLabel.String = txt;
            %comp.UIAxes1.YLabel.FontSize = 14;
            %grid on
            comp.UIAxes1.Title.String = 'ADC Proyección de datos en 2 dimensiones';
            percent =  sum(explained(1:2));
            txt = ['Variabilidad explicada = ' int2str(percent) '%'];
            comp.UIAxes1.Subtitle.String = txt;

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Update underlying components
            comp.AnlisisdedatosLabel.Text = comp.Title;
            comp.StatusDropDown.Value = comp.Status;
            comp.GridLayout.BackgroundColor = comp.BackgroundColor;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 644 602];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout2 = uigridlayout(comp);
            comp.Panel = uipanel(comp.GridLayout2);
            comp.GridLayout = uigridlayout(comp.Panel);
            comp.TabGroup = uitabgroup(comp.GridLayout);
            comp.CargadedatosTab = uitab(comp.TabGroup);
            comp.CargadedatosTab.Title = 'Carga de datos';
            comp.GridLayout3 = uigridlayout(comp.CargadedatosTab);
            comp.StatusDropDown = uidropdown(comp.GridLayout3);
            comp.StatusDropDown.Items = {'PCA', 'LDA', 'CDA'};
            comp.StatusDropDown.Enable = 'off';
            comp.StatusDropDown.Value = 'PCA';
            comp.MtododeanlisisLabel = uilabel(comp.GridLayout3);
            comp.MtododeanlisisLabel.Enable = 'off';
            comp.MtododeanlisisLabel.Text = 'Método de análisis';
            comp.ImportDataButton = uibutton(comp.GridLayout3, 'push');
            comp.ImportDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImportDataButtonPushed, true);
            comp.ImportDataButton.Text = 'Import Data';
            comp.UITable = uitable(comp.GridLayout3);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.RealizaranlisisButton = uibutton(comp.GridLayout3, 'push');
            comp.RealizaranlisisButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RealizaranlisisButtonPushed, true);
            comp.RealizaranlisisButton.Enable = 'off';
            comp.RealizaranlisisButton.Text = 'Realizar análisis';
            comp.VisualizacindedatosTab = uitab(comp.TabGroup);
            comp.VisualizacindedatosTab.Title = 'Visualización de datos';
            comp.GridLayout4 = uigridlayout(comp.VisualizacindedatosTab);
            comp.UIAxes1 = uiaxes(comp.GridLayout4);
            title(comp.UIAxes1, 'Original Data')
            xlabel(comp.UIAxes1, 'Age')
            ylabel(comp.UIAxes1, 'Diastolic Blood Pressure')
            comp.UIAxes2 = uiaxes(comp.GridLayout4);
            title(comp.UIAxes2, 'Updated Data')
            xlabel(comp.UIAxes2, 'Age')
            ylabel(comp.UIAxes2, 'Diastolic Blood Pressure')
            comp.BtnGraph1 = uibutton(comp.GridLayout4, 'push');
            comp.BtnGraph1.Text = 'Zoom';
            comp.BtnGraph2 = uibutton(comp.GridLayout4, 'push');
            comp.BtnGraph2.Text = 'Zoom';
            comp.AnlisisdedatosLabel = uilabel(comp.GridLayout);
            comp.AnlisisdedatosLabel.Text = 'Análisis de datos';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end